classdef cvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        SettingsMenu                matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        MainContentPanel            matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        VisualizationStyleDropDown  matlab.ui.control.DropDown
        VisualizationStyleDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)        
        HelpHTML
    end
    
    properties (Access = public)
        Views       % struct of all available views for the app
        OverlayClass   % class for all imagedata and the overlay transformations
    end
    
    methods (Access = private)
        
        function closeHelpHTML(app, src, event)
            disp('Event empfangen:');
            disp(event.Data);
            if isfield(event.Data, 'type') && strcmp(event.Data.type, 'closeHelp')
                delete(app.HelpHTML);
                app.HelpHTML = [];
            end
        end
        
        function loadViews(app)
            viewsFolder = fullfile(pwd, 'src/visual');
            files = dir(fullfile(viewsFolder, '*.m'));

            disp("Loading views from folder: " + viewsFolder);
            disp("Found view files:");
            disp({files.name});
    
            app.Views = struct();  % Key: view name, Value: view object
            viewNames = {};
    
            for k = 1:numel(files)
                [~, className] = fileparts(files(k).name);
                disp("Trying to load view class: " + className);
                       
                % Call static getName method to get display name
                viewName = feval([className '.getName']);
                
                % Instantiate view
                viewObj = feval(className, app);

                % Store in app.Views
                app.Views.(viewName) = viewObj;
                viewNames{end+1} = viewName;
            end

            disp("Final list of views:");
            disp(viewNames);
    
            % Update dropdown options
            app.VisualizationStyleDropDown.Items = viewNames;
            if ~isempty(viewNames)
                app.VisualizationStyleDropDown.Value = "TimeSlider";
                app.Views.("TimeSlider").show();  % Show initial view
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % include all required files and classes and fucntions and
            % stuff
            addpath(fullfile(pwd, 'src'));
            addpath(fullfile(pwd, 'src/visual'));
            addpath(fullfile(pwd, 'src/overlay'));

            loadViews(app);  % Dynamically register all views
        end

        % Value changed function: VisualizationStyleDropDown
        function VisualizationStyleDropDownValueChanged(app, event)
            selected = app.VisualizationStyleDropDown.Value;
        
            % Hide all views
            viewNames = fieldnames(app.Views);
            for k = 1:numel(viewNames)
                app.Views.(viewNames{k}).hide();
            end
        
            % Show selected
            if isfield(app.Views, selected)
                app.Views.(selected).show();
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            images = utils.loadImagesFromFolder();
            disp(class(images))
            if isempty(images)
                uialert(app.UIFigure, 'No valid images found with pattern YYYY_MM.jpg', 'No Images');
                return;
            end

            app.OverlayClass = calcOverlay(images);
            disp(class(app.OverlayClass.imageArray))
            disp("test")

            % Update views, etc.
            viewNames = fieldnames(app.Views);
            for k = 1:numel(viewNames)
                app.Views.(viewNames{k}).onImLoad();
            end
            
            selected = app.VisualizationStyleDropDown.Value;
            if isfield(app.Views, selected)
                app.Views.(selected).update();
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app.UIFigure)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tif'}, 'Bild speichern als');

            if ischar(file)
                exportgraphics(app.UIAxes, fullfile(path, file));
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            if ~isempty(app.HelpHTML) && isvalid(app.HelpHTML)
                delete(app.HelpHTML);
                app.HelpHTML = [];
                return;
            end
        
            helpFile = fullfile(pwd, 'help.html');
            if ~isfile(helpFile)
                uialert(app.UIFigure, 'Hilfedatei nicht gefunden.', 'Fehler');
                return;
            end
        
            app.HelpHTML = uihtml(app.UIFigure, ...
                'HTMLSource', helpFile, ...
                'Position', [100 100 600 400]);
        
            % Reagiere auf JavaScript-Nachricht
            app.HelpHTML.DataChangedFcn = @(src, event) closeHelpHTML(app, src, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.HelpMenu = uimenu(app.SettingsMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.VisualizationStyleDropDownLabel = uilabel(app.GridLayout2);
            app.VisualizationStyleDropDownLabel.Text = 'Visualization Style';
            app.VisualizationStyleDropDown = uidropdown(app.GridLayout2);
            app.VisualizationStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationStyleDropDownValueChanged, true);
            app.MainContentPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end